classdef myofibril_profiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyofibrilProfilerUIFigure      matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        ExportResultsMenu              matlab.ui.container.Menu
        LoadMeasurementMenu            matlab.ui.container.Menu
        ProfilerPanel                  matlab.ui.container.Panel
        SarcomereMean                  matlab.ui.control.UIAxes
        Sarcomeres                     matlab.ui.control.UIAxes
        ProfileIntensity               matlab.ui.control.UIAxes
        ProfilerControlsPanel          matlab.ui.container.Panel
        ProminenceEditField            matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel       matlab.ui.control.Label
        CalibrationumpxEditField       matlab.ui.control.NumericEditField
        CalibrationumpxEditFieldLabel  matlab.ui.control.Label
        ImageSpinner                   matlab.ui.control.Spinner
        ImageSpinnerLabel              matlab.ui.control.Label
        ImageDisplayPanel              matlab.ui.container.Panel
        SelectPointsButton             matlab.ui.control.Button
        ImageAxes                      matlab.ui.control.UIAxes
        DataHandlerPanel               matlab.ui.container.Panel
        ImageFileEditField             matlab.ui.control.EditField
        ImageFileEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        myofibril_data = []
    end
    
    methods (Access = public)
        
        function ExtractProfiles(app)
            
            im_file = app.myofibril_data.image_file;
            selected_im = app.ImageSpinner.Value;
            xs = app.myofibril_data.profile.xs;
            ys = app.myofibril_data.profile.ys;
            prominence = app.ProminenceEditField.Value;
            calibration = app.CalibrationumpxEditField.Value;

            im = im_file{1,1}{1,1};

            im_profile = improfile(im, xs, ys);
            x_profile = 1:numel(im_profile);

            [pks, locs] = findpeaks(-im_profile, ...
            'MinPeakProminence', prominence * range(im_profile));
            x_sarc_profile = linspace(0, 1, 1000);

            no_of_sarcomeres = numel(locs) - 1;

            for i = 1 : no_of_sarcomeres
                sarc_len(i) = calibration*(locs(i+1) - locs(i))
            end

            mean_sarc_len = mean(sarc_len)

            for i = 1 : no_of_sarcomeres
                profile_indices = locs(i) : locs(i+1);
                x_temp = normalize(profile_indices, 'range')
                y_sarc_profile(i, :) = interp1(x_temp, im_profile(profile_indices), ...
                    x_sarc_profile);
                y_sarc_profile(i,:) = normalize(y_sarc_profile(i,:),'range');
            end

            mean_sarc_profile = mean(y_sarc_profile);

            fwhm_ix(1) = find(mean_sarc_profile >= 0.5,1,'first')
            fwhm_ix(2) = find(mean_sarc_profile >= 0.5,1,'last')

            hold(app.ProfileIntensity,'on')
            plot(app.ProfileIntensity,x_profile,im_profile,'b-');
            scatter(app.ProfileIntensity,locs,-pks,80,'MarkerEdgeColor',[0 0 0],...
                                                      'MarkerFaceColor',[0 1 0],...
                                                      'MarkerFaceAlpha',0.5)
            
            
            plot(app.Sarcomeres,x_sarc_profile,y_sarc_profile)
            colororder(app.Sarcomeres,parula(size(y_sarc_profile,1)))
            shadedErrorBar2(x_sarc_profile, y_sarc_profile, {@mean, @std},'-k',0,app.SarcomereMean);
            hold(app.SarcomereMean,'on')
            plot(app.SarcomereMean,x_sarc_profile(fwhm_ix(1):fwhm_ix(2)),linspace(mean_sarc_profile(fwhm_ix(1)),...
                mean_sarc_profile(fwhm_ix(2)),numel(fwhm_ix(1):fwhm_ix(2))),':m')

            app.myofibril_data.profile.intensity = im_profile;
            app.myofibril_data.profile.sarcomere_intensities = y_sarc_profile;
            app.myofibril_data.profile.sarcomere_location = x_sarc_profile;


        end
        
        function RefreshDisplay(app)

            ax = {'ProfileIntensity','Sarcomeres','SarcomereMean'};
            
            for i = 1:numel(ax)
                cla(app.(ax{i}))
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('utilities'))
            movegui(app.MyofibrilProfilerUIFigure,'center')
            colormap(app.ImageAxes, 'gray');
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file_string,path_string]=uigetfile2( ...
                {'*.nd2'}, ...
                'Select Image File');
            if (path_string~=0)
                delete(f)
                app.myofibril_data = [];
                app.myofibril_data.image_file_string = fullfile(path_string,file_string);
                app.ImageFileEditField.Value = app.myofibril_data.image_file_string;
                app.myofibril_data.image_file = bfopen(app.myofibril_data.image_file_string);
                im = app.myofibril_data.image_file;
                sz = size(im{1,1});

                app.ImageSpinner.Value = 1;
                app.ImageSpinner.Limits = [1 sz(1)];
                app.RefreshDisplay

                center_image_with_preserved_aspect_ratio(im{1,1}{1,1},app.ImageAxes)
                
            end
        end

        % Value changed function: ImageSpinner
        function ImageSpinnerValueChanged(app, event)
            value = app.ImageSpinner.Value;
            im = app.myofibril_data.image_file;
            center_image_with_preserved_aspect_ratio(im{1,1}{value,1},app.ImageAxes)
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            
            if  isfield(app.myofibril_data,'profile')
                app.myofibril_data.profile = [];
                cla(app.ImageAxes)
                center_image_with_preserved_aspect_ratio(app.myofibril_data.image_file{1,1}{app.ImageSpinner.Value,1},app.ImageAxes)
                app.RefreshDisplay
            end

            app.myofibril_data.profile.roi = drawpolyline(app.ImageAxes,'LineWidth',1E-32);

            
            x = app.myofibril_data.profile.roi.Position(:,1);
            y = app.myofibril_data.profile.roi.Position(:,2);
            cs = spline(x, y);
            app.myofibril_data.profile.xs = linspace(x(1), x(end), 1000);
            app.myofibril_data.profile.ys = ppval(cs, app.myofibril_data.profile.xs);

            hold(app.ImageAxes,'on')
            plot(app.ImageAxes,app.myofibril_data.profile.xs,app.myofibril_data.profile.ys,"Color",'m')

            addlistener(app.myofibril_data.profile.roi,"ROIMoved",@(src,evt) update_profile(evt));
            app.ExtractProfiles


            function update_profile(evt)
                x_upt = app.myofibril_data.profile.roi.Position(:,1);
                y_upt = app.myofibril_data.profile.roi.Position(:,2);
                cs_upt = spline(x_upt, y_upt);
                xs_upt = linspace(x_upt(1), x_upt(end), 1000);
                ys_upt = ppval(cs_upt, xs_upt);
                app.myofibril_data.profile.xs = linspace(x_upt(1), x_upt(end), 1000);
                app.myofibril_data.profile.ys = ppval(cs_upt, app.myofibril_data.profile.xs);
                app.RefreshDisplay;
                app.ExtractProfiles;
                app.ImageAxes
            end

        end

        % Value changed function: ProminenceEditField
        function ProminenceEditFieldValueChanged(app, event)
            app.RefreshDisplay;
            app.ExtractProfiles;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyofibrilProfilerUIFigure = uifigure('Visible', 'off');
            app.MyofibrilProfilerUIFigure.Name = 'MyofibrilProfiler';
            app.Menu = uimenu(app.MyofibrilProfilerUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadImageMenu = uimenu(app.Menu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.ExportResultsMenu = uimenu(app.Menu);
            app.ExportResultsMenu.Text = 'Export Results';
            app.LoadMeasurementMenu = uimenu(app.Menu);
            app.LoadMeasurementMenu.Text = 'Load Measurement';
            app.DataHandlerPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.DataHandlerPanel.Title = 'Data Handler';
            app.ImageFileEditFieldLabel = uilabel(app.DataHandlerPanel);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.ImageFileEditField = uieditfield(app.DataHandlerPanel, 'text');
            app.ImageFileEditField.Editable = 'off';
            app.ImageDisplayPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.ImageAxes = uiaxes(app.ImageDisplayPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.SelectPointsButton = uibutton(app.ImageDisplayPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.ProfilerControlsPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ProfilerControlsPanel.Title = 'Profiler Controls';
            app.ImageSpinnerLabel = uilabel(app.ProfilerControlsPanel);
            app.ImageSpinnerLabel.Text = 'Image #';
            app.ImageSpinner = uispinner(app.ProfilerControlsPanel);
            app.ImageSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageSpinnerValueChanged, true);
            app.CalibrationumpxEditFieldLabel = uilabel(app.ProfilerControlsPanel);
            app.CalibrationumpxEditFieldLabel.Text = 'Calibration (um/px)';
            app.CalibrationumpxEditField = uieditfield(app.ProfilerControlsPanel, 'numeric');
            app.CalibrationumpxEditField.Limits = [0 Inf];
            app.CalibrationumpxEditField.Value = 1;
            app.ProminenceEditFieldLabel = uilabel(app.ProfilerControlsPanel);
            app.ProminenceEditFieldLabel.Text = 'Prominence ';
            app.ProminenceEditField = uieditfield(app.ProfilerControlsPanel, 'numeric');
            app.ProminenceEditField.Limits = [0 1];
            app.ProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @ProminenceEditFieldValueChanged, true);
            app.ProminenceEditField.Value = 0.5;
            app.ProfilerPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ProfilerPanel.Title = 'Profiler Panel';
            app.ProfileIntensity = uiaxes(app.ProfilerPanel);
            title(app.ProfileIntensity, 'Profile Intensity')
            ylabel(app.ProfileIntensity, 'Intensity (A.U.)')
            app.Sarcomeres = uiaxes(app.ProfilerPanel);
            title(app.Sarcomeres, 'Sarcomere Intensities')
            ylabel(app.Sarcomeres, 'Normalized Intensity')
            app.SarcomereMean = uiaxes(app.ProfilerPanel);
            title(app.SarcomereMean, 'Average Sarcomere Intensity')
            ylabel(app.SarcomereMean, 'Normalized Intensity')
            % Show the figure after all components are created
            app.MyofibrilProfilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myofibril_profiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyofibrilProfilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyofibrilProfilerUIFigure)
        end
    end
end